classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        SolveButton       matlab.ui.control.Button
        ApplyLoadsBoundaryConditionsButton  matlab.ui.control.Button
        CreateMeshButton  matlab.ui.control.Button
        CreateToolButton  matlab.ui.control.Button
        UIAxes3           matlab.ui.control.UIAxes
        UIAxes2           matlab.ui.control.UIAxes
        UIAxes_2          matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        n_elements % Number of elements generated in the mesh
        n_nodes %Number of nodes in the generated mesh

        E %Youngs modulus of the selected material
        A %Area of the element

        ncon %Connection between nodes

        X %Coordiantes of nodes 
        Y

        NDU %Nodal degree of freedom   
        dzero %Setting some nodes to zero degrees of freedom (no displacment)

        F %Force vector acting on the nodes 
        v %Poissons ratio
        t %Thickeness of material

        % Initiate Matrices
        KE %Elemntal stiffness matrix
        K  %Global sitffness matrix of the piece
    
        KM %Duplicating 


        Wt %Top width of the isoceles trapezium
        Wb %Bottom width of the isoceles trapezium
        H %Height of isoceles trapezium

        U

        Ex
        Ey
        Gxy

        Sx
        Sy
        Sxy

        Ux
        Uy
        Umag

        yield_strength %yeild strength of the material (optional)
        tensile_strength %Ultimate tensile strength
        compressive_strength %Compressive strength
        Ductile %How ductile is material
        Xb %X coordinates of the tool geometry boundary
        Yb %Y coordiantes of the tool geometry boundary
        Sim_strength
        ToolApp
        MeshAppp
        

        L_ref% refined length (from left)
        nx_ref% divisions in refined zone
        nx_coarse% divisions in rest
        ny%Divisions in the Y direction

        resetmesh %Keep track of the reset mesh state
    end

    methods (Access = public)
        
        function receiveGeometry(app, Xb, Yb, Wt, Wb, H, t)

        app.Xb = Xb;
        app.Yb = Yb;
        app.Wt = Wt;
        app.Wb = Wb;
        app.H  = H;
        plot(app.UIAxes, Xb, Yb);
        axis(app.UIAxes, 'equal');
        app.CreateMeshButton.Enable = 'on';

        end

        function receiveMatProp(app,E,v,t,tensile_strength,...
                  compressive_strength,yield_strength,Sim_strength,Ductile)
            app.E = E;
            app.v =v;
            app.t = t;
            app.tensile_strength = tensile_strength;
            app.compressive_strength = compressive_strength;
            app.yield_strength = yield_strength;
            app.Sim_strength = Sim_strength;
            app.Ductile = Ductile;
            %{
            %app.YoungsModulusGPaLabel.Text = ['Youngs Modulos (GPa): ', num2str(app.E)];
                app.YoungsModulusGPaLabel.Text = ...
        ['Youngs Modulus (GPa): ', num2str(E/1e9)];
            %}

        end

        function receiveMeshInfo(app,L_ref, nx_ref, nx_coarse,ny,resetmesh)
        app.resetmesh = resetmesh;
        if app.resetmesh == false
        app.L_ref = L_ref;
        app.nx_ref = nx_ref;
        app.nx_coarse = nx_coarse;
        app.ny = ny;
        
        % =========================
        % TOP EDGE (simple split)
        % =========================
        xL = app.Xb(4);   % top-left
        xR = app.Xb(3);   % top-right

        yL = app.Yb(4);
        yR = app.Yb(3);

        L_total = xR - xL;

        % split point
        x_split = xL + L_ref;
        
        % refined region
        x_top1 = linspace(xL, x_split, nx_ref);
        
        % coarse region
        x_top2 = linspace(x_split, xR, nx_coarse);
        
        % combine (remove duplicate)
        x_top = [x_top1, x_top2(2:end)];
        
        % interpolate y (straight line)
        y_top = yL + (yR - yL) * (x_top - xL) / L_total;
        
        nx = length(x_top);
        
        % =========================
        % BOTTOM EDGE (same idea)
        % =========================
        xL_bot = app.Xb(1);
        xR_bot = app.Xb(2);
        
        yL_bot = app.Yb(1);
        yR_bot = app.Yb(2);
        
        x_bot = xL_bot + (xR_bot - xL_bot) * (x_top - xL) / L_total;
        y_bot = yL_bot + (yR_bot - yL_bot) * (x_top - xL) / L_total;
        % =========================
        % INTERPOLATE MESH
        % =========================
        t_vals = linspace(0,1,ny);
        
        app.X = [];
        app.Y = [];
        
        for j = 1:ny
            t = t_vals(j);
        
            x_row = (1-t)*x_top + t*x_bot;
            y_row = (1-t)*y_top + t*y_bot;
        
            app.X = [app.X; x_row'];
            app.Y = [app.Y; y_row'];
        end
        
        % =========================
        % CONNECTIVITY
        % =========================
        app.ncon = [];
        
        for j = 1:(ny-1)
            for i = 1:(nx-1)
                n1 = (j-1)*nx + i;
                n2 = n1 + 1;
                n3 = n1 + nx;
                n4 = n3 + 1;
        
                app.ncon = [app.ncon; n1 n2 n3];
                app.ncon = [app.ncon; n2 n4 n3];
            end
        end
        
        app.n_nodes = length(app.X);
        app.n_elements = size(app.ncon,1);
        
        patch('Faces', app.ncon, ...
            'Vertices', [app.X app.Y], ...
            'FaceColor', 'none', ...
            'EdgeColor', 'k', ...
            'Parent', app.UIAxes_2);
        axis(app.UIAxes_2, 'equal');
       

        app.ApplyLoadsBoundaryConditionsButton.Enable = 'on';
   else
 % ===== RESET =====
        cla(app.UIAxes_2);

        app.ncon = [];
        app.X = [];
        app.Y = [];

        app.n_nodes = 0;
        app.n_elements = 0;

        app.ApplyLoadsBoundaryConditionsButton.Enable = 'off';
        end
    end

function display_structure(app,n_element,ncon,X,Y,U)

for i = 1:n_element

n1 = ncon(i,1);
n2 = ncon(i,2);
n3 = ncon(i,3);

x1 = X(n1);
x2 = X(n2);
x3 = X(n3);
x4 = x1;

y1 = Y(n1);
y2 = Y(n2);
y3 = Y(n3);
y4 = y1;

u1 = U(2*n1-1);
v1 = U(2*n1);

u2 = U(2*n2-1);
v2 = U(2*n2);

u3 = U(2*n3-1);
v3 = U(2*n3);

xf1 = X(n1) + 1000*u1;
xf2 = X(n2) + 1000*u2;
xf3 = X(n3) + 1000*u3;
xf4 = xf1;

yf1 = Y(n1) + 1000*v1;
yf2 = Y(n2) + 1000*v2;
yf3 = Y(n3) + 1000*v3;
yf4 = yf1;

plot(app.UIAxes2, ...
    [xf1 xf2 xf3 xf4], ...
    [yf1 yf2 yf3 yf4], ...
    'LineWidth', 0.5, ...
    'Color', 'red');

axis(app.UIAxes2, 'equal');
hold(app.UIAxes2, 'on');
% axis equal;
% plot([xf1 xf2 xf3 xf4],[yf1 yf2 yf3 yf4],'LineWidth',0.5,'Color','red')
% hold on;
%{
plot([x1 x2 x3 x4],[y1 y2 y3 y4],'LineWidth',2)

hold on;

plot([x1 x2 x3 x4],[y1 y2 y3 y4],'ro')
%}
end
end
    

    function nodalValues = averageElementToNodes(app, elemValues)

    nodalValues = zeros(app.n_nodes,1);
    count = zeros(app.n_nodes,1);

    for i = 1:app.n_elements
        n1 = app.ncon(i,1);
        n2 = app.ncon(i,2);
        n3 = app.ncon(i,3);

        nodalValues(n1) = nodalValues(n1) + elemValues(i);
        nodalValues(n2) = nodalValues(n2) + elemValues(i);
        nodalValues(n3) = nodalValues(n3) + elemValues(i);

        count(n1) = count(n1) + 1;
        count(n2) = count(n2) + 1;
        count(n3) = count(n3) + 1;
    end

    nodalValues = nodalValues ./ count;
end

    function drawContourLines(app, nodalField, plotTitle)

    cla(app.UIAxes2);
    hold(app.UIAxes2, 'on');
    colormap(app.UIAxes2, jet);
    cmap = colormap(app.UIAxes2);
    ncol = size(cmap,1);

    % draw mesh lightly in background
    patch(app.UIAxes2, ...
        'Faces', app.ncon, ...
        'Vertices', [app.X app.Y], ...
        'FaceColor', 'none', ...
        'EdgeColor', [0.7 0.7 0.7], ...
        'LineWidth', 0.5);

    n_levels = 10;
    levels = linspace(min(nodalField), max(nodalField), n_levels);

    for L = 1:length(levels)

        levelValue = levels(L);
        cidx = round((L-1)/(length(levels)-1) * (ncol-1)) + 1;
        color = cmap(cidx,:);

        for i = 1:app.n_elements

            nodes = app.ncon(i,:);
            x = app.X(nodes);
            y = app.Y(nodes);
            s = nodalField(nodes);

            pts = [];

            edges = [1 2; 2 3; 3 1];

            for e = 1:3
                nA = edges(e,1);
                nB = edges(e,2);

                sA = s(nA);
                sB = s(nB);

                if (sA - levelValue) * (sB - levelValue) < 0
                    lambda = (levelValue - sA) / (sB - sA);

                    x_cross = x(nA) + lambda * (x(nB) - x(nA));
                    y_cross = y(nA) + lambda * (y(nB) - y(nA));

                    pts = [pts; x_cross y_cross];
                end
            end

            if size(pts,1) == 2
                % map level to colour index
                plot(app.UIAxes2, pts(:,1), pts(:,2), ...
                    'Color', color, 'LineWidth', 1.5);
            end
        end
    end
    
    caxis(app.UIAxes2, [min(nodalField) max(nodalField)]);
    cb = colorbar(app.UIAxes2);
    cb.Label.String = plotTitle;
    
    axis(app.UIAxes2, 'equal');
    app.UIAxes2.Title.String = plotTitle;
    app.UIAxes2.XLabel.String = 'X';
    app.UIAxes2.YLabel.String = 'Y';

    hold(app.UIAxes2, 'off');
end

    function plotSelectedContour(app, resultName)

    switch resultName

        case 'Ux'
            nodalField = app.Ux;
            app.drawContourLines(nodalField, 'Contour Plot: Ux');

        case 'Uy'
            nodalField = app.Uy;
            app.drawContourLines(nodalField, 'Contour Plot: Uy');

        case 'Umag'
            nodalField = app.Umag;
            app.drawContourLines(nodalField, 'Contour Plot: |U|');

        case 'Ex'
            nodalField = app.averageElementToNodes(app.Ex);
            app.drawContourLines(nodalField, 'Contour Plot: Ex');

        case 'Ey'
            nodalField = app.averageElementToNodes(app.Ey);
            app.drawContourLines(nodalField, 'Contour Plot: Ey');

        case 'Gxy'
            nodalField = app.averageElementToNodes(app.Gxy);
            app.drawContourLines(nodalField, 'Contour Plot: Gxy');

        case 'Sx'
            nodalField = app.averageElementToNodes(app.Sx);
            app.drawContourLines(nodalField, 'Contour Plot: Sx');

        case 'Sy'
            nodalField = app.averageElementToNodes(app.Sy);
            app.drawContourLines(nodalField, 'Contour Plot: Sy');

        case 'Sxy'
            nodalField = app.averageElementToNodes(app.Sxy);
            app.drawContourLines(nodalField, 'Contour Plot: Sxy');
    end
end

end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CreateMeshButton.Enable = 'off';
            app.ApplyLoadsBoundaryConditionsButton.Enable = 'off';
            app.SolveButton.Enable = 'off';
        end

        % Callback function
        function GetdataButtonPushed(app, event)
            data = readmatrix('structure_data.xlsx');

            app.n_element = data(1,1);
            app.n_nodes   = data(1,2);

            app.E = data(1,8);
            app.A = data(1,9);
            
            app.ncon = [data(:,3), data(:,4), data(:,5)];
            
            app.X = data(:,6);
            app.Y = data(:,7);
            
            app.NDU   = data(1,11);
            app.dzero = data(:,12);
            
            app.F = data(:,10);
            
            app.v = data(1,13);
            app.t = data(1,14);
            
            % Initiate Matrices
            app.KE = zeros(6);
            app.K  = zeros(2*app.n_nodes);
        end

        % Callback function
        function PreprocessButtonPushed(app, event)
            app.K = zeros(2*app.n_nodes, 2*app.n_nodes);

            for i = 1:app.n_element

                KE = app.pre_processing(i);

                n1 = app.ncon(i,1);
                n2 = app.ncon(i,2);
                n3 = app.ncon(i,3);

                ROC = zeros(1,6);
                ROC(1) = 2*n1 - 1;
                ROC(2) = 2*n1;
                ROC(3) = 2*n2 - 1;
                ROC(4) = 2*n2;
                ROC(5) = 2*n3 - 1;
                ROC(6) = 2*n3;

                for IX = 1:6
                    MI = ROC(IX);
                    for JX = 1:6
                        MJ = ROC(JX);
                        app.K(MI,MJ) = app.K(MI,MJ) + KE(IX,JX);
                    end
                end
            end
        end

        % Callback function
        function SolveButtonPushed(app, event)
            app.KM = app.K;

            for k = 1:app.NDU
                n = app.dzero(k);
                app.KM(n,:) = 0;
            end

            for k = 1:app.NDU
                n = app.dzero(k);
                app.KM(:,n) = 0;
            end

            for k = 1:app.NDU
                n = app.dzero(k);
                app.KM(n,n) = 1;
            end

            app.U = app.KM \ app.F;
        end

        % Button pushed function: CreateToolButton
        function CreateToolButtonPushed(app, event)

                app.ToolApp = ToolParameters;   % normal creation
                 app.ToolApp.MainApp = app;      % PASS reference AFTER creation
        end

        % Button pushed function: CreateMeshButton
        function CreateMeshButtonPushed(app, event)
                 app.MeshAppp = MeshApp;   % normal creation
                 app.MeshAppp.MainApp = app;      % PASS reference AFTER creation
        end

        % Button pushed function: ApplyLoadsBoundaryConditionsButton
        function ApplyLoadsBoundaryConditionsButtonPushed(app, event)
                 % Start with zero global load vector
    app.F = zeros(2*app.n_nodes,1);

    % ----------------------------------------
    % Find contact nodes on the top surface
    % between x = 0 and L_ref (user defined refine area)
    % ----------------------------------------
    tolY = 1e-10;
    Fc = 100;
    Ft= -500;
    contactNodes = find( ...
        app.X >= 0.0000 & app.X <= app.L_ref & abs(app.Y - 0.0000) < tolY);

    if isempty(contactNodes)
        error('No contact nodes found for contact line load.');
    end

    % ----------------------------------------
    % Sort contact nodes from left to right
    % ----------------------------------------
    [~,idx] = sort(app.X(contactNodes));
    contactNodes = contactNodes(idx);

    disp('Contact nodes used for line load = ');
    disp(contactNodes);

    disp('Number of contact nodes = ');
    disp(length(contactNodes));

    % ----------------------------------------
    % Total contact length
    % ----------------------------------------
    xContact = app.X(contactNodes);

    Lc = xContact(end) - xContact(1);

    if Lc <= 0
        error('Contact length is zero or negative.');
    end

    % ----------------------------------------
    % Force per unit length
    % ----------------------------------------
    qx = Fc / Lc;
    qy = Ft / Lc;

    % ----------------------------------------
    % Tributary length for each node
    % ----------------------------------------
    tributary = zeros(length(contactNodes),1);

    for i = 1:length(contactNodes)

        if i == 1
            tributary(i) = (xContact(i+1) - xContact(i)) / 2;

        elseif i == length(contactNodes)
            tributary(i) = (xContact(i) - xContact(i-1)) / 2;

        else
            tributary(i) = (xContact(i+1) - xContact(i-1)) / 2;
        end

    end

    % ----------------------------------------
    % Convert line load into nodal forces
    % ----------------------------------------
    Fx_nodes = qx * tributary;
    Fy_nodes = qy * tributary;

    % Check totals
    disp('Total Fx applied = ');
    disp(sum(Fx_nodes));

    disp('Total Fy applied = ');
    disp(sum(Fy_nodes));

    % ----------------------------------------
    % Build global load vector
    % ----------------------------------------
    for i = 1:length(contactNodes)

        n = contactNodes(i);

        app.F(2*n - 1) = app.F(2*n - 1) + Fx_nodes(i);
        app.F(2*n)     = app.F(2*n)     + Fy_nodes(i);

    end

%=================APPLICATION OF TOOL CLAMPING===========
    toll = 1e-6;
    %{
    % Top edge
    topNodes = find(abs(app.Y - app.Yb(3)) < toll);
    
    % Left edge
    leftNodes = find(abs(app.X - app.Xb(1)) < toll);
    
    % Right edge
    rightNodes = find(abs(app.X - app.Xb(2)) < toll);
    %}

    % Bottom edge
    bottomNodes = find(abs(app.Y - app.Yb(1)) < toll);


    app.dzero = [];

    for k = 1:length(bottomNodes)
        n = bottomNodes(k);
        app.dzero = [app.dzero; 2*n-1; 2*n];
    end


   app.dzero = unique(app.dzero);
    app.NDU = length(app.dzero);
    app.SolveButton.Enable = 'on';

        end

        % Button pushed function: SolveButton
        function SolveButtonPushed2(app, event)
            app.K = zeros(2*app.n_nodes);
            for i = 1:app.n_elements

                n1 = app.ncon(i,1);
                n2 = app.ncon(i,2);
                n3 = app.ncon(i,3);
            
                x1 = app.X(n1); y1 = app.Y(n1);
                x2 = app.X(n2); y2 = app.Y(n2);
                x3 = app.X(n3); y3 = app.Y(n3);
            
                Ae = 0.5 * det([1 x1 y1;
                                1 x2 y2;
                                1 x3 y3]);
            
                Ae = abs(Ae);
            
                b1 = y2 - y3;
                b2 = y3 - y1;
                b3 = y1 - y2;
            
                c1 = x3 - x2;
                c2 = x1 - x3;
                c3 = x2 - x1;
            
                B = (1/(2*Ae))*[b1 0  b2 0  b3 0;
                                 0 c1 0 c2 0 c3;
                                 c1 b1 c2 b2 c3 b3];
            
                D = (app.E/(1-app.v^2))*[1 app.v 0;
                                 app.v 1 0;
                                 0 0 (1-app.v)/2];
            disp(size(B))
disp(size(D))
disp(app.E)
disp(app.v)
disp(app.t)
                app.KE = (app.t)*Ae*(B.')*D*B;
            
                ROC = [2*n1-1 2*n1 2*n2-1 2*n2 2*n3-1 2*n3];

                for ix = 1:6
                    for jx = 1:6
                        app.K(ROC(ix),ROC(jx)) = app.K(ROC(ix),ROC(jx)) + app.KE(ix,jx);
                    end
                end
            end

%% =========================
% 8. APPLY BCs
% =========================

app.KM = app.K;

for k = 1:app.NDU
    n = app.dzero(k);
    app.KM(n,:) = 0;
    app.KM(:,n) = 0;
    app.KM(n,n) = 1;
end

%% =========================
% 9. SOLVE
% =========================

app.U = app.KM \ app.F;
U = app.U;

%% =========================
% 10. POST PROCESS
% =========================
Ex = zeros(app.n_elements,1);
Ey = zeros(app.n_elements,1);
Gxy = zeros(app.n_elements,1);

Sx = zeros(app.n_elements,1);
Sy = zeros(app.n_elements,1);
Sxy = zeros(app.n_elements,1);

Ae_all = zeros(app.n_elements,1);

for i = 1:app.n_elements

    n1 = app.ncon(i,1);
    n2 = app.ncon(i,2);
    n3 = app.ncon(i,3);

    x1 = app.X(n1); y1 = app.Y(n1);
    x2 = app.X(n2); y2 = app.Y(n2);
    x3 = app.X(n3); y3 = app.Y(n3);

    Ae = 0.5 * det([1 x1 y1;
                    1 x2 y2;
                    1 x3 y3]);

    Ae = abs(Ae);
    Ae_all(i) = Ae;

    b1 = y2 - y3;
    b2 = y3 - y1;
    b3 = y1 - y2;

    c1 = x3 - x2;
    c2 = x1 - x3;
    c3 = x2 - x1;

    B = (1/(2*Ae))*[b1 0  b2 0  b3 0;
                     0 c1 0 c2 0 c3;
                     c1 b1 c2 b2 c3 b3];

    D = (app.E/(1-app.v^2))*[1 app.v 0;
                     app.v 1 0;
                     0 0 (1-app.v)/2];

    d = [U(2*n1-1); U(2*n1);
         U(2*n2-1); U(2*n2);
         U(2*n3-1); U(2*n3)];

    e = B*d;
    Sigma = D * e;

    Ex(i) = e(1);
    Ey(i) = e(2);
    Gxy(i) = e(3);

    Sx(i)  = Sigma(1);
    Sy(i)  = Sigma(2);
    Sxy(i) = Sigma(3);
end

app.Ex = Ex;
app.Ey = Ey;
app.Gxy = Gxy;

app.Sx = Sx;
app.Sy = Sy;
app.Sxy = Sxy;

app.Ux = U(1:2:end);
app.Uy = U(2:2:end);
app.Umag = sqrt(app.Ux.^2 + app.Uy.^2);

% Sx_node = zeros(app.n_nodes,1);
% count = zeros(app.n_nodes,1);
% 
% for i = 1:app.n_elements
%     n1 = app.ncon(i,1);
%     n2 = app.ncon(i,2);
%     n3 = app.ncon(i,3);
% 
%     Sx_node(n1) = Sx_node(n1) + Sx(i);
%     Sx_node(n2) = Sx_node(n2) + Sx(i);
%     Sx_node(n3) = Sx_node(n3) + Sx(i);
% 
%     count(n1) = count(n1) + 1;
%     count(n2) = count(n2) + 1;
%     count(n3) = count(n3) + 1;
% end
% 
% Sx_node = Sx_node ./ count;
% 
% n_levels = 10;
% levels = linspace(min(Sx_node), max(Sx_node), n_levels);
% figure; hold on;
% 
% for L = 1:length(levels)
%     sigma_target = levels(L);
% 
%     for i = 1:app.n_elements
% 
%         nodes = app.ncon(i,:);
%         x = app.X(nodes);
%         y = app.Y(nodes);
%         s = Sx_node(nodes);
% 
%         pts = [];
% 
%         edges = [1 2; 2 3; 3 1];
% 
%         for e = 1:3
%             nA = edges(e,1);
%             nB = edges(e,2);
% 
%             sA = s(nA);
%             sB = s(nB);
% 
%             % Check if contour crosses this edge
%             if (sA - sigma_target)*(sB - sigma_target) < 0
% 
%                 lambda = (sigma_target - sA)/(sB - sA);
% 
%                 x_cross = x(nA) + lambda*(x(nB)-x(nA));
%                 y_cross = y(nA) + lambda*(y(nB)-y(nA));
% 
%                 pts = [pts; x_cross y_cross];
%             end
%         end
% 
%         % If two intersection points → draw line segment
%         if size(pts,1) == 2
%             plot(pts(:,1), pts(:,2), 'k');
%         end
%     end
% end

app.display_structure(app.n_elements,app.ncon,app.X,app.Y,app.U)
app.plotSelectedContour('Sx');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Tool geometry')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Generated Mesh')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Deformed Mesh')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.CreateToolButton = uibutton(app.UIFigure, 'push');
            app.CreateToolButton.ButtonPushedFcn = createCallbackFcn(app, @CreateToolButtonPushed, true);
            app.CreateToolButton.Text = 'Create Tool';
            app.CreateMeshButton = uibutton(app.UIFigure, 'push');
            app.CreateMeshButton.ButtonPushedFcn = createCallbackFcn(app, @CreateMeshButtonPushed, true);
            app.CreateMeshButton.Text = 'Create Mesh';
            app.ApplyLoadsBoundaryConditionsButton = uibutton(app.UIFigure, 'push');
            app.ApplyLoadsBoundaryConditionsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyLoadsBoundaryConditionsButtonPushed, true);
            app.ApplyLoadsBoundaryConditionsButton.Text = 'Apply Loads & Boundary Conditions';
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed2, true);
            app.SolveButton.Text = 'Solve';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end