classdef ToolParameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ApplyButton                     matlab.ui.control.Button
        PoissonsRatioEditField          matlab.ui.control.NumericEditField
        PoissonsRatioEditFieldLabel     matlab.ui.control.Label
        ThicknessmEditField             matlab.ui.control.NumericEditField
        ThicknessmEditFieldLabel        matlab.ui.control.Label
        MaterialPropertiesLabel         matlab.ui.control.Label
        ToolGeometryLabel               matlab.ui.control.Label
        HeightmEditField                matlab.ui.control.NumericEditField
        HeightmEditFieldLabel           matlab.ui.control.Label
        TopWidthmEditField              matlab.ui.control.NumericEditField
        TopWidthmEditFieldLabel         matlab.ui.control.Label
        MaterialDropDown                matlab.ui.control.DropDown
        MaterialLabel                   matlab.ui.control.Label
        YoungsModulusGPaEditField       matlab.ui.control.NumericEditField
        YoungsModulusGPaEditFieldLabel  matlab.ui.control.Label
        BottomWidthmEditField           matlab.ui.control.NumericEditField
        BottomWidthmEditFieldLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        Property % Description
        E %Youngs modulus
        v %Poissons Ratio
        Wt %Top width of the isoceles trapezium
        Wb %Bottom width of the isoceles trapezium
        H %Height of isoceles trapezium
        t %Thickness of material

        yeild_strength %yeild strength of the material (optional)
        tensile_strength %Ultimate tensile strength
        compressive_strength %Compressive strength
        Ductile %How ductile is material
        
        Sim_strength %User check for material failure
        FS %Factor of safety

        MainApp % Description
    end

    methods (Access = public)
        
        function [Xb, Yb] = isosceles_trapezium(app,Wt,Wb,H)
            % Generates coordinates of an isosceles trapezium
            % Wb = bottom width
            % Wt = top width
            % H  = height
            %
            % Output:
            % X, Y = vectors of vertex coordinates (closed shape)

            % Check validity
            if Wt < Wb
                error('Top width must be greater than the bottom width');
            end
        
            % Compute horizontal offset
            %dx = (Wb - Wt) / 2;
        
            % % Define vertices (centered at origin)
            % X = [-Wb/2; Wb/2; Wt/2; -Wt/2; -Wb/2];
            % Y = [0;0;H;H;0];
            % % Shift so top-left corner is (0,0)
            % Xb = X + Wt/2;
            % Yb = Y - H;
                % Horizontal offset (centers the top edge)
    dx = (Wb - Wt) / 2;

    % Define vertices (bottom on y = 0, centered at x = 0)
    X = [-Wb/2; Wb/2; Wb/2 - dx; -Wb/2 + dx; -Wb/2];
    Y = [0; 0; H; H; 0];
    Xb = X + Wt/2;
    Yb = Y - H;
        end
        
        function setMaterialProp(app, material)
            
            switch material
                case 'Tungsten Carbide'
               app.E = 640e9;
               app.v = 0.2;
               % app.tensile_strength = 450e6;
               % app.compressive_strength = 5000e6;
               % app.Ductile = false;

               case 'M2 High Speed Steel'
               app.E = 200e9;
               app.v = 0.29;
               %app.yeild_strength = 1550e6;
               %app.Ductile = true;

               case 'Custom'
                   %if app.Sim_strength == 1
                    % app.E = app.YoungsModulusGPaEditField.Value*10^9;
                    % app.v = app.PoissonsRatioEditField.Value;
                    % app.tensile_strength = app.TensileStrengthMPaEditField.Value*10^6;
                    % app.compressive_strength = app.CompressiveStrengthMPaEditField.Value*10^6;
                    % app.yeild_strength = app.YeildStrengthMPaEditField.Value*10^6;
                    % app.FS = app.FactorofSafetyEditField.Value;
                   %else
                    app.E = app.YoungsModulusGPaEditField.Value*10^9;
                    app.v = app.PoissonsRatioEditField.Value;
                    %app.t = app.ThicknessmEditField.Value;
                   %end
                
            end        
            
        end


        function updateMaterialUI(app, material)

            switch material

               case 'Tungsten Carbide'
               app.YoungsModulusGPaEditField.Enable = 'off';
               app.PoissonsRatioEditField.Enable = 'off';
               %app.DuctilityDropDown.Enable = 'off';
               
               %pp.TensileStrengthMPaEditField.Enable = 'off';
              % app.CompressiveStrengthMPaEditField.Enable = 'off';
               %app.YeildStrengthMPaEditField.Enable = 'off';

                case 'M2 High Speed Steel'
               app.YoungsModulusGPaEditField.Enable = 'off';
               app.PoissonsRatioEditField.Enable = 'off';
              % app.DuctilityDropDown.Enable = 'off';

               %app.TensileStrengthMPaEditField.Enable = 'off';
               %app.CompressiveStrengthMPaEditField.Enable = 'off';
               %app.YeildStrengthMPaEditField.Enable = 'off';

                case 'Custom'
               app.YoungsModulusGPaEditField.Enable = 'on';
               app.PoissonsRatioEditField.Enable = 'on';
               %app.DuctilityDropDown.Enable = 'on';
               %app.Ductile = true;
               %app.DuctilityDropDown.Value = 'Undefined';
               %app.SimulateMaterialStrengthOptionalCheckBox.Enable = 'on';
               end


        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % To grey out and disable the edit field
            app.YoungsModulusGPaEditField.Enable = 'off';
            app.PoissonsRatioEditField.Enable = 'off';
            %app.DuctilityDropDown.Enable = 'off';
            %app.TensileStrengthMPaEditField.Enable = 'off';
            %app.CompressiveStrengthMPaEditField.Enable = 'off';
            %app.YeildStrengthMPaEditField.Enable = 'off';
            app.MaterialDropDown.Value = 'Tungsten Carbide';
            app.setMaterialProp('Tungsten Carbide');
            %app.MaterialStrengthParametersPanel.Enable = 'off';
            %app.SimulateMaterialStrengthOptionalCheckBox.Enable = 'off';

               %app.E = 640e9;
               %app.v = 0.2;
               %app.tensile_strength = 450e6;
               %app.compressive_strength = 5000e6;
               %app.Ductile = false;

        end

        % Callback function
        function GenerateButtonPushed(app, event)
           app.Wt = app.TopWidthmEditField.Value; %Saves value from feild
           app.Wb = app.BottomWidthmEditField.Value;
           app.H = app.HeightmEditField.Value;
           [app.Xb, app.Yb] = app.isosceles_trapezium(app.Wt, app.Wb, app.H);

           plot(app.UIAxes,app.Xb, app.Yb);
           axis(app.UIAxes, 'equal')

        end

        % Callback function
        function SaveButtonPushed(app, event)
            %Saving material properties
           app.t = app.ThicknessmEditField.Value;
           app.yeild_strength = app.YeildStrengthMPaEditField.Value;
           app.tensile_strength = app.TensileStrengthMPaEditField.Value;
           app.compressive_strength = app.CompressiveStrengthMPaEditField.Value;

        end

        % Value changed function: MaterialDropDown
        function MaterialDropDownValueChanged(app, event)
           material = app.MaterialDropDown.Value;
           app.setMaterialProp(material);
           app.updateMaterialUI(material);
        
        end

        % Callback function
        function DuctilityDropDownValueChanged(app, event)
            value = app.DuctilityDropDown.Value;

            switch value
                case 'Undefined'
                    app.TensileStrengthMPaEditField.Enable = 'off';
                    app.CompressiveStrengthMPaEditField.Enable = 'off';
                    app.YeildStrengthMPaEditField.Enable = 'off';
                case 'Ductile'

                    app.TensileStrengthMPaEditField.Enable = 'off';
                    app.CompressiveStrengthMPaEditField.Enable = 'off';
                    app.YeildStrengthMPaEditField.Enable = 'on';
                    app.Ductile = true;
                    
                case 'Brittle'
                    app.Ductile = false;
                    app.TensileStrengthMPaEditField.Enable = 'on';
                    app.CompressiveStrengthMPaEditField.Enable = 'on';
                    app.YeildStrengthMPaEditField.Enable = 'off';

            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            %{
            % Read values from tool UI
    app.MainApp.Wt = app.TopWidthmEditField.Value;
    app.MainApp.Wb = app.BottomWidthmEditField.Value;
    app.MainApp.H  = app.HeightmEditField.Value;
    app.MainApp.t  = app.ThicknessmEditField.Value;

    % Generate geometry in MAIN app
    [app.MainApp.Xb, app.MainApp.Yb] = ...
        app.MainApp.isosceles_trapezium( ...
            app.MainApp.Wt, app.MainApp.Wb, app.MainApp.H);

    % Update MAIN plot
    plot(app.MainApp.UIAxes, app.MainApp.Xb, app.MainApp.Yb);
    axis(app.MainApp.UIAxes, 'equal');
    %         %}
    % app.Wt = app.TopWidthmEditField.Value;
    % app.Wb = app.BottomWidthmEditField.Value;
    % app.H  = app.HeightmEditField.Value;
    % app.t = app.ThicknessmEditField.Value;
    % 
    % [Xb, Yb] = app.isosceles_trapezium(app.Wt, app.Wb, app.H);
    % app.MainApp.receiveMatProp(app.E,app.v,app.t);
    % app.MainApp.receiveGeometry(Xb, Yb, app.Wt, app.Wb, app.H);
    
    %app.MainApp.receiveMatProp(app.E,app.v,app.t,app.tensile_strength,app.compressive_strength,app.yeild_strength,app.Sim_strength, app.Ductile)

            %}


    app.Wt = app.TopWidthmEditField.Value;
    app.Wb = app.BottomWidthmEditField.Value;
    app.H  = app.HeightmEditField.Value;
    app.t  = app.ThicknessmEditField.Value;

    % Guard against uninitialised material
    if isempty(app.E) || app.E == 0
        app.setMaterialProp(app.MaterialDropDown.Value);
    end

    fprintf('Applying: E=%.3e | v=%.3f | t=%.4e\n', app.E, app.v, app.t);

    [Xb, Yb] = app.isosceles_trapezium(app.Wt, app.Wb, app.H);
    app.MainApp.receiveMatProp(app.E, app.v, app.t);
    app.MainApp.receiveGeometry(Xb, Yb, app.Wt, app.Wb, app.H);
    
        end

        % Callback function
        function SimulateMaterialStrengthOptionalCheckBoxValueChanged(app, event)
            app.Sim_strength = app.SimulateMaterialStrengthOptionalCheckBox.Value;
            if app.Sim_strength == 1
                app.MaterialStrengthParametersPanel.Enable = 'on';
          
                else
                app.MaterialStrengthParametersPanel.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BottomWidthmEditFieldLabel = uilabel(app.UIFigure);
            app.BottomWidthmEditFieldLabel.Text = 'Bottom Width (m)';
            app.BottomWidthmEditField = uieditfield(app.UIFigure, 'numeric');
            app.YoungsModulusGPaEditFieldLabel = uilabel(app.UIFigure);
            app.YoungsModulusGPaEditFieldLabel.Text = 'Youngs Modulus (GPa)';
            app.YoungsModulusGPaEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaterialLabel = uilabel(app.UIFigure);
            app.MaterialLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.UIFigure);
            app.MaterialDropDown.Items = {'Tungsten Carbide', 'M2 High Speed Steel', 'Custom'};
            app.MaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @MaterialDropDownValueChanged, true);
            app.MaterialDropDown.Value = 'Tungsten Carbide';
            app.TopWidthmEditFieldLabel = uilabel(app.UIFigure);
            app.TopWidthmEditFieldLabel.Text = 'Top Width (m)';
            app.TopWidthmEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightmEditFieldLabel = uilabel(app.UIFigure);
            app.HeightmEditFieldLabel.Text = 'Height (m)';
            app.HeightmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ToolGeometryLabel = uilabel(app.UIFigure);
            app.ToolGeometryLabel.Text = 'Tool Geometry';
            app.MaterialPropertiesLabel = uilabel(app.UIFigure);
            app.MaterialPropertiesLabel.Text = 'Material Properties';
            app.ThicknessmEditFieldLabel = uilabel(app.UIFigure);
            app.ThicknessmEditFieldLabel.Text = 'Thickness (m)';
            app.ThicknessmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PoissonsRatioEditFieldLabel = uilabel(app.UIFigure);
            app.PoissonsRatioEditFieldLabel.Text = 'Poissons Ratio';
            app.PoissonsRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ToolParameters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end